classdef MEA_Analysis_Toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MEA_Analysis_ToolboxUIFigure    matlab.ui.Figure
        FileTab                         matlab.ui.container.TabGroup
        OpenfileTab                     matlab.ui.container.Tab
        SpikecutoutPlot                 matlab.ui.control.UIAxes
        OpenfileButton                  matlab.ui.control.StateButton
        MEAlayoutButtonGroup            matlab.ui.container.ButtonGroup
        chanBtn21                       matlab.ui.control.RadioButton
        chanBtn22                       matlab.ui.control.RadioButton
        chanBtn23                       matlab.ui.control.RadioButton
        chanBtn24                       matlab.ui.control.RadioButton
        chanBtn25                       matlab.ui.control.RadioButton
        chanBtn26                       matlab.ui.control.RadioButton
        chanBtn27                       matlab.ui.control.RadioButton
        chanBtn31                       matlab.ui.control.RadioButton
        chanBtn32                       matlab.ui.control.RadioButton
        chanBtn33                       matlab.ui.control.RadioButton
        chanBtn34                       matlab.ui.control.RadioButton
        chanBtn35                       matlab.ui.control.RadioButton
        chanBtn36                       matlab.ui.control.RadioButton
        chanBtn37                       matlab.ui.control.RadioButton
        chanBtn38                       matlab.ui.control.RadioButton
        chanBtn41                       matlab.ui.control.RadioButton
        chanBtn42                       matlab.ui.control.RadioButton
        chanBtn43                       matlab.ui.control.RadioButton
        chanBtn44                       matlab.ui.control.RadioButton
        chanBtn45                       matlab.ui.control.RadioButton
        chanBtn46                       matlab.ui.control.RadioButton
        chanBtn47                       matlab.ui.control.RadioButton
        chanBtn48                       matlab.ui.control.RadioButton
        chanBtn51                       matlab.ui.control.RadioButton
        chanBtn52                       matlab.ui.control.RadioButton
        chanBtn53                       matlab.ui.control.RadioButton
        chanBtn54                       matlab.ui.control.RadioButton
        chanBtn55                       matlab.ui.control.RadioButton
        chanBtn56                       matlab.ui.control.RadioButton
        chanBtn57                       matlab.ui.control.RadioButton
        chanBtn58                       matlab.ui.control.RadioButton
        chanBtn61                       matlab.ui.control.RadioButton
        chanBtn62                       matlab.ui.control.RadioButton
        chanBtn63                       matlab.ui.control.RadioButton
        chanBtn64                       matlab.ui.control.RadioButton
        chanBtn65                       matlab.ui.control.RadioButton
        chanBtn66                       matlab.ui.control.RadioButton
        chanBtn67                       matlab.ui.control.RadioButton
        chanBtn68                       matlab.ui.control.RadioButton
        chanBtn71                       matlab.ui.control.RadioButton
        chanBtn72                       matlab.ui.control.RadioButton
        chanBtn73                       matlab.ui.control.RadioButton
        chanBtn74                       matlab.ui.control.RadioButton
        chanBtn75                       matlab.ui.control.RadioButton
        chanBtn76                       matlab.ui.control.RadioButton
        chanBtn77                       matlab.ui.control.RadioButton
        chanBtn78                       matlab.ui.control.RadioButton
        chanBtn82                       matlab.ui.control.RadioButton
        chanBtn83                       matlab.ui.control.RadioButton
        chanBtn84                       matlab.ui.control.RadioButton
        chanBtn85                       matlab.ui.control.RadioButton
        chanBtn86                       matlab.ui.control.RadioButton
        chanBtn87                       matlab.ui.control.RadioButton
        chanBtn12                       matlab.ui.control.RadioButton
        chanBtn13                       matlab.ui.control.RadioButton
        chanBtn14                       matlab.ui.control.RadioButton
        chanBtn_ref                     matlab.ui.control.RadioButton
        chanBtn16                       matlab.ui.control.RadioButton
        chanBtn17                       matlab.ui.control.RadioButton
        chanBtn28                       matlab.ui.control.RadioButton
        AnalogDataPlot                  matlab.ui.control.UIAxes
        FigureButtonSpike               matlab.ui.control.Button
        FigureButtonAnalog              matlab.ui.control.Button
        RecordinginfoPanel              matlab.ui.container.Panel
        DateLabel                       matlab.ui.control.Label
        DurationLabel                   matlab.ui.control.Label
        DateLabelField                  matlab.ui.control.Label
        DurationLabelField              matlab.ui.control.Label
        FilenamelabelField              matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        TimesegmentlengthtoanalyseLabel  matlab.ui.control.Label
        SegmentlengthEditField          matlab.ui.control.NumericEditField
        SamplingrateLabel               matlab.ui.control.Label
        SamplingrateLabelField          matlab.ui.control.Label
        TsSliderLabel                   matlab.ui.control.Label
        TsSlider                        matlab.ui.control.Slider
        FunctionsTab                    matlab.ui.container.TabGroup
        AnalogTab                       matlab.ui.container.Tab
        CrosscorrelationPanel           matlab.ui.container.Panel
        PlotCrossCorBtn                 matlab.ui.control.StateButton
        analogExportPanel               matlab.ui.container.Panel
        ExportDataBtn                   matlab.ui.control.Button
        PDSPanel                        matlab.ui.container.Panel
        PlotPSDButton                   matlab.ui.control.Button
        MaxvalueLabel                   matlab.ui.control.Label
        PSDMaxvalueEditField            matlab.ui.control.NumericEditField
        analogPCAPanel                  matlab.ui.container.Panel
        OutlierstoremoveListBox_2Label  matlab.ui.control.Label
        analogOutliersListBox           matlab.ui.control.ListBox
        analogPCABtn                    matlab.ui.control.StateButton
        analogPCAoutlierLabel           matlab.ui.control.Label
        NumberofcomponentsEditFieldLabel  matlab.ui.control.Label
        analogPCAnumCompEditField       matlab.ui.control.NumericEditField
        variancePanel                   matlab.ui.container.Panel
        varianceBtn                     matlab.ui.control.Button
        SpikeCutoutsTab                 matlab.ui.container.Tab
        spikecutoutPCAPanel             matlab.ui.container.Panel
        OutlierstoremoveListBoxLabel    matlab.ui.control.Label
        spikecutoutOutliersListBox      matlab.ui.control.ListBox
        spikecutoutPCAButton            matlab.ui.control.StateButton
        spikecutoutPCAoutlierLabel      matlab.ui.control.Label
        NumberofcomponentsEditFieldLabel_2  matlab.ui.control.Label
        spikecutoutPCAnumCompEditField  matlab.ui.control.NumericEditField
        SpiketrainsTab                  matlab.ui.container.Tab
        SpiCoDynPanel                   matlab.ui.container.Panel
        ExportToolconnectBtn            matlab.ui.control.Button
        ImportToolconnectBtn            matlab.ui.control.Button
        ExportToolconnectLabel1         matlab.ui.control.Label
        ExportToolconnectLabel2         matlab.ui.control.Label
        ExportToolconnectLabel3         matlab.ui.control.Label
        BargraphPanel                   matlab.ui.container.Panel
        BargraphBtn                     matlab.ui.control.Button
        PlotmeanfiringratePanel         matlab.ui.container.Panel
        MeanfiringrateBtn               matlab.ui.control.StateButton
        maxMFRLabel                     matlab.ui.control.Label
        maxMFREditField                 matlab.ui.control.NumericEditField
        spikessecLabel                  matlab.ui.control.Label
        spikecountPanel                 matlab.ui.container.Panel
        deltaEditFieldLabel             matlab.ui.control.Label
        deltaEditField                  matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        thresholdEditFieldLabel         matlab.ui.control.Label
        thresholdEditField              matlab.ui.control.NumericEditField
        CountspikesBtn                  matlab.ui.control.StateButton
        rasterplotPanel                 matlab.ui.container.Panel
        RasterplotBtn                   matlab.ui.control.StateButton
    end


    properties (Access = private)
        fileName            % string with name of open file
        analogLabels        % cell array of labels string from analog recording
        spikeLabels         % cell array of labels string from spike train recording
        spikecutoutLabels   % cell array of labels string from spike cutout recording
        analogData          % McsAnalogStream object with analog data
        spikeCuts           % McsCutoutSegment object with spike cutouts
        spikeData           % McsTimeStampStream object with spike trains
        dataFile            % McsData object: cell array of recordings in the HDF5 file
        selectedChan        % string label with current selected channel 
        recDuration         % double with the total duration of the recording 
        samplingrate        % double with the samplingrate in the recording 
    end

    methods (Access = private)
    
        function plotAnalog(app)
            if ~isempty(app.analogData)
                chanIndex = getLabelIndex({app.selectedChan},app.analogLabels);
                data = app.analogData.ChannelData(chanIndex,:)*1e-6; 
                time = McsHDF5.TickToSec(app.analogData.ChannelDataTimeStamps);
                plot(app.AnalogDataPlot,time,data );
                app.AnalogDataPlot.XLim = [time(1) time(end)];
            end
        end
        
        function plotSpikeCut(app)
            if ~isempty(app.spikeCuts)
                time = 0:1/app.samplingrate*1e3:3;
                if ~isempty(app.spikeCuts.SegmentData{1,1})
                    chanIndex = getLabelIndex({app.selectedChan},app.spikecutoutLabels);
                    data = app.spikeCuts.SegmentData{1,chanIndex}.*1e-6;
                    timestamps = app.spikeCuts.SegmentDataTimeStamps{chanIndex}.*1e-6;
                    
                    if app.TsSlider.Value > app.recDuration - app.SegmentlengthEditField.Value
                        tStart = app.recDuration - app.SegmentlengthEditField.Value;
                    else
                        tStart = app.TsSlider.Value;
                    end
                    tEnd = tStart+app.SegmentlengthEditField.Value;
                    data = data(:,timestamps>=tStart & timestamps<=tEnd);
                    if ~isempty(data)
                        plot(app.SpikecutoutPlot,time, data);
                        hold(app.SpikecutoutPlot,'on')
                        plot(app.SpikecutoutPlot,time,mean(data,2),'-k','LineWidth',2);
                        hold(app.SpikecutoutPlot,'off')
                    else
                        plot(app.SpikecutoutPlot,time,zeros(1,length(time)) );
                        
                    end
                else
                    plot(app.SpikecutoutPlot,time,zeros(1,length(time)) );
                end    
            end
        end

        function loadAnalogData(app)
            if ~isempty(app.fileName)
                if ~isempty(app.dataFile.Recording{1}.AnalogStream)
                    if app.TsSlider.Value > app.recDuration - app.SegmentlengthEditField.Value
                        tStart = app.recDuration - app.SegmentlengthEditField.Value;
                    else
                        tStart = app.TsSlider.Value;
                    end
                    cfg = [];
                    cfg.window = [tStart, tStart + app.SegmentlengthEditField.Value]; % time range in seconds
                
                    app.analogData = app.dataFile.Recording{1}.AnalogStream{1}.readPartialChannelData(cfg);
                    app.analogLabels = app.analogData.Info.Label;
                end
            end
        end
        
        function loadSpikeData(app)
            if ~isempty(app.fileName)
                if ~isempty(app.dataFile.Recording{1}.TimeStampStream)
                    if app.TsSlider.Value > app.recDuration - app.SegmentlengthEditField.Value
                        tStart = app.recDuration - app.SegmentlengthEditField.Value;
                    else
                        tStart = app.TsSlider.Value;
                    end
                    cfg = [];
                    cfg.window = [tStart, app.TsSlider.Value + app.SegmentlengthEditField.Value]; % time range in seconds
                    app.spikeData = app.dataFile.Recording{1}.TimeStampStream{1}.readPartialTimeStampData(cfg);
                    app.spikeLabels = app.spikeData.Info.Label;
                end
            end
        end
        
        function loadSpikeCuts(app)
            if ~isempty(app.fileName)
                if ~ isempty(app.dataFile.Recording{1}.SegmentStream)
                    cfg = [];
                    app.spikeCuts = app.dataFile.Recording{1}.SegmentStream{1}.readPartialSegmentData(cfg);
                    app.spikecutoutLabels = app.spikeCuts.Info.Label;
                end
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            % Initialize default values
            app.selectedChan = app.MEAlayoutButtonGroup.SelectedObject.Text;
            
            %% Add to path all the subdirectories in the toolbox
            topdir = fileparts(which(mfilename));
            addpath(genpath(topdir));
      
        end

        % Value changed function: analogPCABtn
        function analogPCA(app, event)
            if app.analogPCABtn.Value == 1
                app.analogPCABtn.Text = 'computing...';
                if ~ isempty(app.analogData)
                    outlierlist = app.analogOutliersListBox.Value;
                    for i=1:length(outlierlist)
                        if isequal(outlierlist{i},'ref')
                            outlierlist{i} = '15';
                        end
                        outliers(i) = str2num(outlierlist{i});
                    end
                    data = app.analogData.ChannelData';
                    numComponents = app.analogPCAnumCompEditField.Value;
                    pcaTimeSeries( data,app.analogLabels,outliers,numComponents,app.samplingrate);
                end
                app.analogPCABtn.Value = 0;
                app.analogPCABtn.Text = 'PCA';
            end
        end

        % Button pushed function: BargraphBtn
        function bargraph(app, event)
            if ~ isempty(app.spikeData) 
                barGraph( app.spikeData.TimeStamps,app.spikeLabels); 
            end
        end

        % Value changed function: PlotCrossCorBtn
        function crosscor(app, event)
            if app.PlotCrossCorBtn.Value ==  1
                app.PlotCrossCorBtn.Text = 'computing...';
                if ~ isempty(app.analogData)
                    data = app.analogData.ChannelData';
                    crosscor(data,app.analogLabels);
                end
                app.PlotCrossCorBtn.Value =  0;
                app.PlotCrossCorBtn.Text = 'Plot CC';
            end
        end

        % Button pushed function: ExportDataBtn
        function export2mat(app, event)
            if ~ isempty(app.analogData)
                % Export raw data to .mat
                data = app.analogData.ChannelData';
                [filename, pathname] = uiputfile('*.mat','Save file as');
                if ~isequal(filename,0) && ~isequal(pathname,0)
                   save([ pathname filename ],'data');
                end
            end
        end

        % Button pushed function: ExportToolconnectBtn
        function exportSpiCoDyn(app, event)
            if ~ isempty(app.spikeData)
                % Export to SpiCoDyn format
                date = app.dataFile.Data.Date;
                if contains(date,'?')
                    date = replace(date,'?','_'); 
                end
                tStart = app.TsSlider.Value;
                tEnd = app.SegmentlengthEditField.Value + app.TsSlider.Value;
                timestamps = app.spikeData.TimeStamps;
                exportSpiCoDyn(timestamps,app.spikeLabels, tStart,tEnd, app.samplingrate,date)
            end
        end

        % Button pushed function: ImportToolconnectBtn
        function importSpiCoDyn(app, event)
            % Import Connectivity matrix from SpiCoDyn end plot graph
            [filename, pathname] = uigetfile('*.txt','Select CM file to plot');
            if ~isequal(filename,0) && ~isequal(pathname,0)
               labels = getLabels();
               cm = load([pathname filename]);
               cfg = [];
               cfg.title = 'Connectivity map - Transfer entropy';
               dirgraph(cm,labels,0,cfg);
            end
        end

        % Value changed function: MeanfiringrateBtn
        function mfr(app, event)
            if app.MeanfiringrateBtn.Value ==  1
                app.MeanfiringrateBtn.Text = 'computing...';
                if ~ isempty(app.spikeData)
                    tStart = app.TsSlider.Value;
                    tEnd = app.SegmentlengthEditField.Value + app.TsSlider.Value;
                    maxValue = app.maxMFREditField.Value;
                    plotMFR( app.spikeData.TimeStamps,app.spikeLabels,(tEnd-tStart), maxValue)
                end
                app.MeanfiringrateBtn.Value = 0;
                app.MeanfiringrateBtn.Text = 'Mean firing rate';
            end
        end

        % Value changed function: OpenfileButton
        function openHDF5Recording(app, event)
            value = app.OpenfileButton.Value;
            if value
               if isempty(which('McsHDF5.McsData'))
                    warndlg({
                        'Install the library using the Matlab Add-On Explorer,',...
                        'or download from this link:',...
                        'http://www.multichannelsystems.com/software/multi-channel-datamanager'},...
                        'McsMatlabDataTools is not installed!');
                    app.OpenfileButton.Value = 0;
                    return
                end 
                
                [app.fileName, filePath] = uigetfile('*.h5','Select HDF5 file with recording');
                if app.fileName ~= 0
                    cfg = [];
                    cfg.dataType = 'double'; 
                    try
                        app.dataFile = McsHDF5.McsData([filePath app.fileName],cfg);
                    catch
                        warndlg('This is not a valid Mcs HDF5 file!', 'Read error');
                        app.OpenfileButton.Value = 0;
                        return
                    end
                    
                    % Update info panel
                    app.FilenamelabelField.Text = app.fileName;
                    app.recDuration = double(app.dataFile.Recording{1,1}.Duration)*1e-6;
                    app.DurationLabelField.Text = [num2str(app.recDuration) ' sec'];
                    app.DateLabelField.Text = app.dataFile.Data.Date;
                    app.MEAlayoutButtonGroup.Title = ['MEA layout: ' app.dataFile.Data.MeaName];
                   
                    % Calculate Sampling rate in Hz
                    if ~isempty(app.dataFile.Recording{1}.AnalogStream)
                        tick = app.dataFile.Recording{1}.AnalogStream{1}.Info.Tick(1);
                        app.samplingrate = 1/(double(tick)*1e-6);
                    elseif ~isempty(app.dataFile.Recording{1}.SegmentStream)
                        tick = app.dataFile.Recording{1}.SegmentStream{1}.SourceInfoChannel.Tick(1);
                        app.samplingrate = 1/(double(tick)*1e-6);
                    else 
                        clear app.dataFile
                        warndlg('File format is incompatible with this toolbox', 'Read error');
                        return
                    end
                    app.SamplingrateLabelField.Text = [num2str(app.samplingrate) ' Hz'];
                    
                    % Update time segment
                    app.TsSlider.Limits = [0 app.recDuration];
                    if app.SegmentlengthEditField.Value > app.recDuration
                        app.SegmentlengthEditField.Value = app.recDuration;
                    end
                         
                    loadAnalogData(app);
                    loadSpikeCuts(app);
                    loadSpikeData(app);
            
                    if ~ isempty(app.spikeCuts)
                        plotSpikeCut(app);
                    end
                    if ~ isempty(app.analogData)
                        plotAnalog(app);
                    end
                end
                app.OpenfileButton.Value = 0;
            end
            
        end

        % Button pushed function: PlotPSDButton
        function pds(app, event)
            if ~ isempty(app.analogData) 
                currentChanIndex = getLabelIndex({app.selectedChan},app.analogLabels);
                data = app.analogData.ChannelData';
                maxValue = app.PSDMaxvalueEditField.Value;
                plotPDS(data(:,currentChanIndex), app.samplingrate, app.selectedChan, maxValue)
            end
        end

        % Button pushed function: FigureButtonAnalog
        function plotAnalogFigure(app, event)
            if ~isempty(app.analogData)   
                time = McsHDF5.TickToSec(app.analogData.ChannelDataTimeStamps);
                plotAnalogdata(app.analogData.ChannelData',time,app.analogLabels,{app.selectedChan})
            end
        end

        % Button pushed function: FigureButtonSpike
        function plotSpikecutoutsFigure(app, event)
            if ~isempty(app.spikeCuts)
                chanIndex = getLabelIndex({app.selectedChan},app.spikecutoutLabels);
                data = app.spikeCuts.SegmentData{1,chanIndex}.*1e-6;
                timestamps = app.spikeCuts.SegmentDataTimeStamps{chanIndex}.*1e-6;
                
                if app.TsSlider.Value > app.recDuration - app.SegmentlengthEditField.Value
                    tStart = app.recDuration - app.SegmentlengthEditField.Value;
                else
                    tStart = app.TsSlider.Value;
                end
                tEnd = tStart+app.SegmentlengthEditField.Value;
                data = data(:,timestamps>=tStart & timestamps<=tEnd);
                plotSpikecutouts(data,app.selectedChan,app.samplingrate)
                
            end
        end

        % Value changed function: RasterplotBtn
        function rasterplot(app, event)
            if app.RasterplotBtn.Value == 1
                app.RasterplotBtn.Text = 'computing...';
                if ~ isempty(app.spikeData)
                    rasterplot(app.spikeData);
                end
                app.RasterplotBtn.Value = 0;
                app.RasterplotBtn.Text = 'Rasterplot';
            end
        end

        % Value changed function: SegmentlengthEditField
        function segmentlengthChanged(app, event)
            value = app.SegmentlengthEditField.Value;
            if ~ isempty(app.dataFile)
                if value > app.recDuration
                    app.SegmentlengthEditField.Value = app.recDuration;
                end
                loadAnalogData(app);
                loadSpikeData(app);
                plotAnalog(app);
                plotSpikeCut(app);
            end
            
        end

        % Selection changed function: MEAlayoutButtonGroup
        function selectChan(app, event)
            app.selectedChan = app.MEAlayoutButtonGroup.SelectedObject.Text;
            if ~ isempty(app.dataFile)
                plotSpikeCut(app);
                plotAnalog(app);
            end
      
        end

        % Value changed function: CountspikesBtn
        function spikecount(app, event)
            if app.CountspikesBtn.Value == 1
                app.CountspikesBtn.Text = 'computing...';
                if ~ isempty(app.spikeData)
                    tStart = app.TsSlider.Value;
                    tEnd = app.SegmentlengthEditField.Value + app.TsSlider.Value;
                    spikecountConnectivity(app.spikeData.TimeStamps,app.spikeLabels,...
                        app.deltaEditField.Value,(tEnd-tStart),app.thresholdEditField.Value);
                end
                app.CountspikesBtn.Value = 0;
                app.CountspikesBtn.Text = 'Count spikes';
            end
        end

        % Value changed function: spikecutoutPCAButton
        function spikecutoutPCA(app, event)
            if app.spikecutoutPCAButton.Value == 1
                app.spikecutoutPCAButton.Text = 'computing...';
                if ~ isempty(app.spikeCuts)
                    outlierlist = app.spikecutoutOutliersListBox.Value;
                    for i=1:length(outlierlist)
                        if isequal(outlierlist{i},'ref')
                            outlierlist{i} = '15';
                        end
                        outliers(i) = str2num(outlierlist{i});
                    end
                    numComponents = app.spikecutoutPCAnumCompEditField.Value;
                    pcaSpikeCutout( app.spikeCuts.SegmentData,app.spikecutoutLabels,outliers,numComponents);
                end
                app.spikecutoutPCAButton.Value = 0;
                app.spikecutoutPCAButton.Text = 'PCA';
            end
        end

        % Value changed function: TsSlider
        function timeSlider(app, event)
            if ~ isempty(app.dataFile)
                loadAnalogData(app);
                loadSpikeData(app);
                plotAnalog(app);
                plotSpikeCut(app);
            end
        end

        % Button pushed function: varianceBtn
        function variance(app, event)
            if ~ isempty(app.analogData) 
                data = app.analogData.ChannelData';
                plotVariance(data,app.analogLabels);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MEA_Analysis_ToolboxUIFigure = uifigure;
            app.MEA_Analysis_ToolboxUIFigure.Name = 'MEA Analysis Toolbox';
            app.FileTab = uitabgroup(app.MEA_Analysis_ToolboxUIFigure);
            app.OpenfileTab = uitab(app.FileTab);
            app.OpenfileTab.Title = 'Open file';
            app.SpikecutoutPlot = uiaxes(app.OpenfileTab);
            title(app.SpikecutoutPlot, 'Spike Cutout')
            xlabel(app.SpikecutoutPlot, 'Time [ms]')
            ylabel(app.SpikecutoutPlot, 'Voltage [\muV]')
            app.OpenfileButton = uibutton(app.OpenfileTab, 'state');
            app.OpenfileButton.ValueChangedFcn = createCallbackFcn(app, @openHDF5Recording, true);
            app.OpenfileButton.Text = 'Open file';
            app.MEAlayoutButtonGroup = uibuttongroup(app.OpenfileTab);
            app.MEAlayoutButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectChan, true);
            app.MEAlayoutButtonGroup.Title = 'MEA layout: 60MEA';
            app.chanBtn21 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn21.Text = '21';
            app.chanBtn22 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn22.Text = '22';
            app.chanBtn23 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn23.Text = '23';
            app.chanBtn24 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn24.Text = '24';
            app.chanBtn25 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn25.Text = '25';
            app.chanBtn26 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn26.Text = '26';
            app.chanBtn27 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn27.Text = '27';
            app.chanBtn31 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn31.Text = '31';
            app.chanBtn32 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn32.Text = '32';
            app.chanBtn33 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn33.Text = '33';
            app.chanBtn34 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn34.Text = '34';
            app.chanBtn35 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn35.Text = '35';
            app.chanBtn36 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn36.Text = '36';
            app.chanBtn37 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn37.Text = '37';
            app.chanBtn38 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn38.Text = '38';
            app.chanBtn41 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn41.Text = '41';
            app.chanBtn42 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn42.Text = '42';
            app.chanBtn43 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn43.Text = '43';
            app.chanBtn44 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn44.Text = '44';
            app.chanBtn45 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn45.Text = '45';
            app.chanBtn45.Value = true;
            app.chanBtn46 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn46.Text = '46';
            app.chanBtn47 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn47.Text = '47';
            app.chanBtn48 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn48.Text = '48';
            app.chanBtn51 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn51.Text = '51';
            app.chanBtn52 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn52.Text = '52';
            app.chanBtn53 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn53.Text = '53';
            app.chanBtn54 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn54.Text = '54';
            app.chanBtn55 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn55.Text = '55';
            app.chanBtn56 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn56.Text = '56';
            app.chanBtn57 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn57.Text = '57';
            app.chanBtn58 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn58.Text = '58';
            app.chanBtn61 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn61.Text = '61';
            app.chanBtn62 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn62.Text = '62';
            app.chanBtn63 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn63.Text = '63';
            app.chanBtn64 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn64.Text = '64';
            app.chanBtn65 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn65.Text = '65';
            app.chanBtn66 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn66.Text = '66';
            app.chanBtn67 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn67.Text = '67';
            app.chanBtn68 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn68.Text = '68';
            app.chanBtn71 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn71.Text = '71';
            app.chanBtn72 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn72.Text = '72';
            app.chanBtn73 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn73.Text = '73';
            app.chanBtn74 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn74.Text = '74';
            app.chanBtn75 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn75.Text = '75';
            app.chanBtn76 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn76.Text = '76';
            app.chanBtn77 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn77.Text = '77';
            app.chanBtn78 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn78.Text = '78';
            app.chanBtn82 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn82.Text = '82';
            app.chanBtn83 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn83.Text = '83';
            app.chanBtn84 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn84.Text = '84';
            app.chanBtn85 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn85.Text = '85';
            app.chanBtn86 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn86.Text = '86';
            app.chanBtn87 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn87.Text = '87';
            app.chanBtn12 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn12.Text = '12';
            app.chanBtn13 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn13.Text = '13';
            app.chanBtn14 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn14.Text = '14';
            app.chanBtn_ref = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn_ref.Text = 'ref';
            app.chanBtn16 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn16.Text = '16';
            app.chanBtn17 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn17.Text = '17';
            app.chanBtn28 = uiradiobutton(app.MEAlayoutButtonGroup);
            app.chanBtn28.Text = '28';
            app.AnalogDataPlot = uiaxes(app.OpenfileTab);
            title(app.AnalogDataPlot, 'Analog data')
            xlabel(app.AnalogDataPlot, 'Time [s]')
            ylabel(app.AnalogDataPlot, 'Voltage [\muV]')
            app.FigureButtonSpike = uibutton(app.OpenfileTab, 'push');
            app.FigureButtonSpike.ButtonPushedFcn = createCallbackFcn(app, @plotSpikecutoutsFigure, true);
            app.FigureButtonSpike.Text = 'Open Figure';
            app.FigureButtonAnalog = uibutton(app.OpenfileTab, 'push');
            app.FigureButtonAnalog.ButtonPushedFcn = createCallbackFcn(app, @plotAnalogFigure, true);
            app.FigureButtonAnalog.Text = 'Open Figure';
            app.RecordinginfoPanel = uipanel(app.OpenfileTab);
            app.RecordinginfoPanel.Title = 'Recording info';
            app.DateLabel = uilabel(app.RecordinginfoPanel);
            app.DateLabel.Text = 'Date:';
            app.DurationLabel = uilabel(app.RecordinginfoPanel);
            app.DurationLabel.Text = 'Duration:';
            app.DateLabelField = uilabel(app.RecordinginfoPanel);
            app.DateLabelField.Text = '';
            app.DurationLabelField = uilabel(app.RecordinginfoPanel);
            app.DurationLabelField.Text = '';
            app.FilenamelabelField = uilabel(app.RecordinginfoPanel);
            app.FilenamelabelField.Text = '';
            app.FilenameLabel = uilabel(app.RecordinginfoPanel);
            app.FilenameLabel.Text = 'File name:';
            app.secLabel = uilabel(app.RecordinginfoPanel);
            app.secLabel.Text = 'sec';
            app.TimesegmentlengthtoanalyseLabel = uilabel(app.RecordinginfoPanel);
            app.TimesegmentlengthtoanalyseLabel.Text = 'Time segment length to analyse';
            app.SegmentlengthEditField = uieditfield(app.RecordinginfoPanel, 'numeric');
            app.SegmentlengthEditField.ValueChangedFcn = createCallbackFcn(app, @segmentlengthChanged, true);
            app.SegmentlengthEditField.Limits = [0 Inf];
            app.SegmentlengthEditField.Value = 10;
            app.SamplingrateLabel = uilabel(app.RecordinginfoPanel);
            app.SamplingrateLabel.Text = 'Sampling rate:';
            app.SamplingrateLabelField = uilabel(app.RecordinginfoPanel);
            app.SamplingrateLabelField.Text = '';
            app.TsSliderLabel = uilabel(app.OpenfileTab);
            app.TsSliderLabel.Text = 'T [s]';
            app.TsSlider = uislider(app.OpenfileTab);
            app.TsSlider.Limits = [0 10];
            app.TsSlider.ValueChangedFcn = createCallbackFcn(app, @timeSlider, true);
            app.FunctionsTab = uitabgroup(app.MEA_Analysis_ToolboxUIFigure);
            app.AnalogTab = uitab(app.FunctionsTab);
            app.AnalogTab.Title = 'Analog';
            app.CrosscorrelationPanel = uipanel(app.AnalogTab);
            app.CrosscorrelationPanel.Title = 'Cross correlation';
            app.PlotCrossCorBtn = uibutton(app.CrosscorrelationPanel, 'state');
            app.PlotCrossCorBtn.ValueChangedFcn = createCallbackFcn(app, @crosscor, true);
            app.PlotCrossCorBtn.Text = 'Plot CC';
            app.analogExportPanel = uipanel(app.AnalogTab);
            app.analogExportPanel.Title = 'Export analog data to .mat ';
            app.ExportDataBtn = uibutton(app.analogExportPanel, 'push');
            app.ExportDataBtn.ButtonPushedFcn = createCallbackFcn(app, @export2mat, true);
            app.ExportDataBtn.Text = 'Export';
            app.PDSPanel = uipanel(app.AnalogTab);
            app.PDSPanel.Title = 'Plot Power Spectral Density for current channel';
            app.PlotPSDButton = uibutton(app.PDSPanel, 'push');
            app.PlotPSDButton.ButtonPushedFcn = createCallbackFcn(app, @pds, true);
            app.PlotPSDButton.Text = 'Plot PSD';
            app.MaxvalueLabel = uilabel(app.PDSPanel);
            app.MaxvalueLabel.Text = 'Max value';
            app.PSDMaxvalueEditField = uieditfield(app.PDSPanel, 'numeric');
            app.PSDMaxvalueEditField.Limits = [0 Inf];
            app.PSDMaxvalueEditField.Value = 1000000000;
            app.analogPCAPanel = uipanel(app.AnalogTab);
            app.analogPCAPanel.Title = 'Exploratory PCA on analog data';
            app.OutlierstoremoveListBox_2Label = uilabel(app.analogPCAPanel);
            app.OutlierstoremoveListBox_2Label.Text = 'Outliers to remove';
            app.analogOutliersListBox = uilistbox(app.analogPCAPanel);
            app.analogOutliersListBox.Items = {'12', '13', '14', 'ref', '16', '17', '21', '22', '23', '24', '25', '26', '27', '28', '31', '32', '33', '34', '35', '36', '37', '38', '41', '42', '43', '44', '45', '46', '47', '48', '51', '52', '53', '54', '55', '56', '57', '58', '61', '62', '63', '64', '65', '66', '67', '68', '71', '72', '73', '74', '75', '76', '77', '78', '82', '83', '84', '85', '86', '87'};
            app.analogOutliersListBox.Multiselect = 'on';
            app.analogOutliersListBox.Value = {'ref'};
            app.analogPCABtn = uibutton(app.analogPCAPanel, 'state');
            app.analogPCABtn.ValueChangedFcn = createCallbackFcn(app, @analogPCA, true);
            app.analogPCABtn.Text = 'PCA';
            app.analogPCAoutlierLabel = uilabel(app.analogPCAPanel);
            app.analogPCAoutlierLabel.Text = '(hold ctrl to select more)';
            app.NumberofcomponentsEditFieldLabel = uilabel(app.analogPCAPanel);
            app.NumberofcomponentsEditFieldLabel.Text = 'Number of  components';
            app.analogPCAnumCompEditField = uieditfield(app.analogPCAPanel, 'numeric');
            app.analogPCAnumCompEditField.Limits = [1 59];
            app.analogPCAnumCompEditField.ValueDisplayFormat = '%.0f';
            app.analogPCAnumCompEditField.Value = 10;
            app.variancePanel = uipanel(app.AnalogTab);
            app.variancePanel.Title = 'Plot variance for each electrode';
            app.varianceBtn = uibutton(app.variancePanel, 'push');
            app.varianceBtn.ButtonPushedFcn = createCallbackFcn(app, @variance, true);
            app.varianceBtn.Text = 'Plot variance';
            app.SpikeCutoutsTab = uitab(app.FunctionsTab);
            app.SpikeCutoutsTab.Title = 'Spike Cutouts';
            app.spikecutoutPCAPanel = uipanel(app.SpikeCutoutsTab);
            app.spikecutoutPCAPanel.Title = 'Exploratory PCA on spike cutouts';
            app.OutlierstoremoveListBoxLabel = uilabel(app.spikecutoutPCAPanel);
            app.OutlierstoremoveListBoxLabel.Text = 'Outliers to remove';
            app.spikecutoutOutliersListBox = uilistbox(app.spikecutoutPCAPanel);
            app.spikecutoutOutliersListBox.Items = {'12', '13', '14', 'ref', '16', '17', '21', '22', '23', '24', '25', '26', '27', '28', '31', '32', '33', '34', '35', '36', '37', '38', '41', '42', '43', '44', '45', '46', '47', '48', '51', '52', '53', '54', '55', '56', '57', '58', '61', '62', '63', '64', '65', '66', '67', '68', '71', '72', '73', '74', '75', '76', '77', '78', '82', '83', '84', '85', '86', '87'};
            app.spikecutoutOutliersListBox.Multiselect = 'on';
            app.spikecutoutOutliersListBox.Value = {'ref'};
            app.spikecutoutPCAButton = uibutton(app.spikecutoutPCAPanel, 'state');
            app.spikecutoutPCAButton.ValueChangedFcn = createCallbackFcn(app, @spikecutoutPCA, true);
            app.spikecutoutPCAButton.Text = 'PCA';
            app.spikecutoutPCAoutlierLabel = uilabel(app.spikecutoutPCAPanel);
            app.spikecutoutPCAoutlierLabel.Text = '(hold ctrl to select more)';
            app.NumberofcomponentsEditFieldLabel_2 = uilabel(app.spikecutoutPCAPanel);
            app.NumberofcomponentsEditFieldLabel_2.Text = 'Number of  components';
            app.spikecutoutPCAnumCompEditField = uieditfield(app.spikecutoutPCAPanel, 'numeric');
            app.spikecutoutPCAnumCompEditField.Limits = [1 59];
            app.spikecutoutPCAnumCompEditField.ValueDisplayFormat = '%.0f';
            app.spikecutoutPCAnumCompEditField.Value = 10;
            app.SpiketrainsTab = uitab(app.FunctionsTab);
            app.SpiketrainsTab.Title = 'Spike trains';
            app.SpiCoDynPanel = uipanel(app.SpiketrainsTab);
            app.SpiCoDynPanel.Title = 'SpiCoDyn';
            app.ExportToolconnectBtn = uibutton(app.SpiCoDynPanel, 'push');
            app.ExportToolconnectBtn.ButtonPushedFcn = createCallbackFcn(app, @exportSpiCoDyn, true);
            app.ExportToolconnectBtn.Text = 'Export';
            app.ImportToolconnectBtn = uibutton(app.SpiCoDynPanel, 'push');
            app.ImportToolconnectBtn.ButtonPushedFcn = createCallbackFcn(app, @importSpiCoDyn, true);
            app.ImportToolconnectBtn.Text = 'Import';
            app.ExportToolconnectLabel1 = uilabel(app.SpiCoDynPanel);
            app.ExportToolconnectLabel1.Text = 'Export spike train to SpiCoDyn format';
            app.ExportToolconnectLabel2 = uilabel(app.SpiCoDynPanel);
            app.ExportToolconnectLabel2.Text = 'Import connectivity matrix from ';
            app.ExportToolconnectLabel3 = uilabel(app.SpiCoDynPanel);
            app.ExportToolconnectLabel3.Text = 'SpiCoDyn and plot connectivity map';
            app.BargraphPanel = uipanel(app.SpiketrainsTab);
            app.BargraphPanel.Title = 'Bargraph with number of spikes in each channel';
            app.BargraphBtn = uibutton(app.BargraphPanel, 'push');
            app.BargraphBtn.ButtonPushedFcn = createCallbackFcn(app, @bargraph, true);
            app.BargraphBtn.Text = 'Bargraph';
            app.PlotmeanfiringratePanel = uipanel(app.SpiketrainsTab);
            app.PlotmeanfiringratePanel.Title = 'Plot mean firing rate';
            app.MeanfiringrateBtn = uibutton(app.PlotmeanfiringratePanel, 'state');
            app.MeanfiringrateBtn.ValueChangedFcn = createCallbackFcn(app, @mfr, true);
            app.MeanfiringrateBtn.Text = 'Mean firing rate';
            app.maxMFRLabel = uilabel(app.PlotmeanfiringratePanel);
            app.maxMFRLabel.Text = 'max MFR';
            app.maxMFREditField = uieditfield(app.PlotmeanfiringratePanel, 'numeric');
            app.maxMFREditField.Limits = [0 Inf];
            app.maxMFREditField.Value = 5;
            app.spikessecLabel = uilabel(app.PlotmeanfiringratePanel);
            app.spikessecLabel.Text = 'spikes/sec';
            app.spikecountPanel = uipanel(app.SpiketrainsTab);
            app.spikecountPanel.Title = 'Connectivity map from spike counting';
            app.deltaEditFieldLabel = uilabel(app.spikecountPanel);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.spikecountPanel, 'numeric');
            app.deltaEditField.Limits = [0 Inf];
            app.deltaEditField.Value = 100;
            app.sLabel = uilabel(app.spikecountPanel);
            app.sLabel.Text = 'µs';
            app.thresholdEditFieldLabel = uilabel(app.spikecountPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.spikecountPanel, 'numeric');
            app.thresholdEditField.Limits = [0 10];
            app.thresholdEditField.ValueDisplayFormat = '%.0f';
            app.thresholdEditField.Value = 1;
            app.CountspikesBtn = uibutton(app.spikecountPanel, 'state');
            app.CountspikesBtn.ValueChangedFcn = createCallbackFcn(app, @spikecount, true);
            app.CountspikesBtn.Text = 'Count spikes';
            app.rasterplotPanel = uipanel(app.SpiketrainsTab);
            app.rasterplotPanel.Title = 'Plot rasterplot';
            app.RasterplotBtn = uibutton(app.rasterplotPanel, 'state');
            app.RasterplotBtn.ValueChangedFcn = createCallbackFcn(app, @rasterplot, true);
            app.RasterplotBtn.Text = 'Rasterplot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MEA_Analysis_Toolbox

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MEA_Analysis_ToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MEA_Analysis_ToolboxUIFigure)
        end
    end
end